classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Board                matlab.ui.container.Tab
        HTML                 matlab.ui.control.HTML
        OscilloscopeKnobs    matlab.ui.container.Tab
        PositionPanel        matlab.ui.container.Panel
        VdivDKnob_2          matlab.ui.control.Knob
        ChannelDLabel_2      matlab.ui.control.Label
        VdivCKnob_2          matlab.ui.control.Knob
        ChannelCLabel_2      matlab.ui.control.Label
        VdivBKnob_2          matlab.ui.control.Knob
        ChannelBLabel_2      matlab.ui.control.Label
        VdivAKnob_2          matlab.ui.control.Knob
        ChannelAKnobLabel_2  matlab.ui.control.Label
        ScalePanel           matlab.ui.container.Panel
        VdivDKnob            matlab.ui.control.Knob
        ChannelDLabel        matlab.ui.control.Label
        VdivCKnob            matlab.ui.control.Knob
        ChannelCLabel        matlab.ui.control.Label
        VdivBKnob            matlab.ui.control.Knob
        ChannelBLabel        matlab.ui.control.Label
        VdivAKnob            matlab.ui.control.Knob
        ChannelAKnobLabel    matlab.ui.control.Label
        TimePerDivKnob       matlab.ui.control.Knob
        TimeLabel            matlab.ui.control.Label
    end


      properties (Access = private)
        OscilloscopeFigure matlab.ui.Figure % Handle for the popup window
        defTimeLimit          double = 0.005 
        TimeLimit          double = 0.005   % Default time window
        VoltageLimits      struct = struct('a', 10, 'b', 10, 'c', 10, 'd', 10) % Volts/Div per channel
        LastPlotTime       double = 0      % For throttling live updates
        PositionOffsets    struct = struct('a', 0, 'b', 0, 'c', 0, 'd', 0) % Vertical position offsets
        PlotHandles = gobjects(1,4) % Store line handles for fast updating
    end


        methods (Access = private)
function updatePopupOscilloscope(app, boardState)
    if isempty(boardState), return; end
    
    simulationResult = run_simulation(boardState, app.TimeLimit);
    
    if isempty(app.OscilloscopeFigure) || ~isvalid(app.OscilloscopeFigure)
        app.OscilloscopeFigure = figure('Name', 'Oscilloscope', 'NumberTitle', 'off', 'Color', 'k');
        ax = axes(app.OscilloscopeFigure);
        set(ax, 'Color', [0.1 0.1 0.1], 'XColor', 'w', 'YColor', 'w');
        title(ax, 'Oscilloscope', 'Color', 'w');
        xlabel(ax, 'Time (s)', 'Color', 'w'); ylabel(ax, 'Divisions', 'Color', 'w');
        grid(ax, 'on'); hold(ax, 'on');
        
        % Initialize empty plots once with default DisplayNames
        colors = {'#3498db', '#f1c40f', '#e74c3c', '#2ecc71'};
        for i = 1:4
            chName = char('A' + i - 1);
            app.PlotHandles(i) = plot(ax, NaN, NaN, 'LineWidth', 1.5, 'Color', colors{i}, 'DisplayName', ['CH ' chName]);
        end
        % Initialize legend to track the DisplayName properties automatically
        legend(ax, 'TextColor', 'w', 'Location', 'northeast');
    end
    
    ax = findobj(app.OscilloscopeFigure, 'Type', 'axes');
    xlim(ax, [0, app.TimeLimit]);
    ylim(ax, [-1, 1]);
    
    % Update data and legend text directly
    for i = 1:4
        ch = char('a' + i - 1);
        signal = simulationResult.oscilloscope.(ch);
        
        if any(signal)
            voltsPerDiv = app.VoltageLimits.(ch);
            positionOffset = app.PositionOffsets.(ch);
            normalized_signal = (signal / voltsPerDiv) + positionOffset;
            
            % Update XData, YData, and the dynamic legend label
            set(app.PlotHandles(i), 'XData', simulationResult.time, 'YData', normalized_signal, ...
                'DisplayName', sprintf('CH %s (%.2f V/div)', upper(ch), voltsPerDiv));
        else
            % Hide inactive channels and label them as Off
            set(app.PlotHandles(i), 'XData', NaN, 'YData', NaN, ...
                'DisplayName', sprintf('CH %s (Off)', upper(ch))); 
        end
    end
end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Data changed function: HTML
        function HTMLDataChanged(app, event)

        boardState = app.HTML.Data; % Get data from JS
        updatePopupOscilloscope(app, boardState); % Call the plotting function


        end

        % Value changing function: VdivAKnob
        function VdivAKnobValueChanging(app, event)
           currentTime = posixtime(datetime('now'));
            if (currentTime - app.LastPlotTime) < 0.05, return; end
            app.LastPlotTime = currentTime;

            app.VoltageLimits.a = event.Value;
            updatePopupOscilloscope(app, app.HTML.Data);
        end

        % Value changed function: VdivBKnob
        function VdivBKnobValueChanged(app, event)
 currentTime = posixtime(datetime('now'));
            if (currentTime - app.LastPlotTime) < 0.05, return; end
            app.LastPlotTime = currentTime;

            app.VoltageLimits.b = event.Value;
            updatePopupOscilloscope(app, app.HTML.Data);
        end

        % Value changed function: VdivCKnob
        function VdivCKnobValueChanged(app, event)
             currentTime = posixtime(datetime('now'));
            if (currentTime - app.LastPlotTime) < 0.05, return; end
            app.LastPlotTime = currentTime;

            app.VoltageLimits.c = event.Value;
            updatePopupOscilloscope(app, app.HTML.Data);
        end

        % Value changed function: VdivDKnob
        function VdivDKnobValueChanged(app, event)
            currentTime = posixtime(datetime('now'));
            if (currentTime - app.LastPlotTime) < 0.05, return; end
            app.LastPlotTime = currentTime;

            app.VoltageLimits.d = event.Value;
            updatePopupOscilloscope(app, app.HTML.Data);
            
        end

        % Value changing function: TimePerDivKnob
        function TimePerDivKnobValueChanging(app, event)
          currentTime = posixtime(datetime('now'));
            if (currentTime - app.LastPlotTime) < 0.05, return; end % Throttle
            app.LastPlotTime = currentTime;
            
            app.TimeLimit = event.Value/10000 *2*app.defTimeLimit;
            updatePopupOscilloscope(app, app.HTML.Data);
     
        end

        % Value changed function: VdivAKnob_2
        function VdivAKnob_2ValueChanged(app, event)
            app.PositionOffsets.a = event.Value;
    updatePopupOscilloscope(app, app.HTML.Data);
        end

        % Value changed function: VdivBKnob_2
        function VdivBKnob_2ValueChanged(app, event)
            app.PositionOffsets.b = event.Value;
    updatePopupOscilloscope(app, app.HTML.Data);
            
        end

        % Value changed function: VdivCKnob_2
        function VdivCKnob_2ValueChanged(app, event)
            app.PositionOffsets.c = event.Value;
    updatePopupOscilloscope(app, app.HTML.Data);
            
        end

        % Value changed function: VdivDKnob_2
        function VdivDKnob_2ValueChanged(app, event)
            app.PositionOffsets.d = event.Value;
    updatePopupOscilloscope(app, app.HTML.Data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Board = uitab(app.TabGroup);
            app.Board.Title = 'Main Board';
            app.HTML = uihtml(app.Board);
            app.HTML.HTMLSource = 'EmonaUI.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.OscilloscopeKnobs = uitab(app.TabGroup);
            app.OscilloscopeKnobs.Title = 'Oscilloscope Control';
            app.TimeLabel = uilabel(app.OscilloscopeKnobs);
            app.TimeLabel.Text = 'Time';
            app.TimePerDivKnob = uiknob(app.OscilloscopeKnobs, 'continuous');
            app.TimePerDivKnob.Limits = [1 10000];
            app.TimePerDivKnob.MajorTicks = 1;
            app.TimePerDivKnob.MajorTickLabels = {''};
            app.TimePerDivKnob.ValueChangingFcn = createCallbackFcn(app, @TimePerDivKnobValueChanging, true);
            app.TimePerDivKnob.Value = 5000;
            app.ScalePanel = uipanel(app.OscilloscopeKnobs);
            app.ScalePanel.Title = 'Scale';
            app.ChannelAKnobLabel = uilabel(app.ScalePanel);
            app.ChannelAKnobLabel.Text = 'Channel A';
            app.VdivAKnob = uiknob(app.ScalePanel, 'continuous');
            app.VdivAKnob.Limits = [1 20];
            app.VdivAKnob.MajorTicks = 1;
            app.VdivAKnob.MajorTickLabels = {''};
            app.VdivAKnob.ValueChangingFcn = createCallbackFcn(app, @VdivAKnobValueChanging, true);
            app.VdivAKnob.Value = 10;
            app.ChannelBLabel = uilabel(app.ScalePanel);
            app.ChannelBLabel.Text = 'Channel B';
            app.VdivBKnob = uiknob(app.ScalePanel, 'continuous');
            app.VdivBKnob.Limits = [1 20];
            app.VdivBKnob.MajorTicks = 1;
            app.VdivBKnob.MajorTickLabels = {''};
            app.VdivBKnob.ValueChangedFcn = createCallbackFcn(app, @VdivBKnobValueChanged, true);
            app.VdivBKnob.Value = 10;
            app.ChannelCLabel = uilabel(app.ScalePanel);
            app.ChannelCLabel.Text = 'Channel C';
            app.VdivCKnob = uiknob(app.ScalePanel, 'continuous');
            app.VdivCKnob.Limits = [1 20];
            app.VdivCKnob.MajorTicks = 1;
            app.VdivCKnob.MajorTickLabels = {''};
            app.VdivCKnob.ValueChangedFcn = createCallbackFcn(app, @VdivCKnobValueChanged, true);
            app.VdivCKnob.Value = 10;
            app.ChannelDLabel = uilabel(app.ScalePanel);
            app.ChannelDLabel.Text = 'Channel D';
            app.VdivDKnob = uiknob(app.ScalePanel, 'continuous');
            app.VdivDKnob.Limits = [1 20];
            app.VdivDKnob.MajorTicks = 1;
            app.VdivDKnob.MajorTickLabels = {''};
            app.VdivDKnob.ValueChangedFcn = createCallbackFcn(app, @VdivDKnobValueChanged, true);
            app.VdivDKnob.Value = 10;
            app.PositionPanel = uipanel(app.OscilloscopeKnobs);
            app.PositionPanel.Title = 'Position';
            app.ChannelAKnobLabel_2 = uilabel(app.PositionPanel);
            app.ChannelAKnobLabel_2.Text = 'Channel A';
            app.VdivAKnob_2 = uiknob(app.PositionPanel, 'continuous');
            app.VdivAKnob_2.Limits = [-1 1];
            app.VdivAKnob_2.MajorTicks = 1;
            app.VdivAKnob_2.MajorTickLabels = {''};
            app.VdivAKnob_2.ValueChangedFcn = createCallbackFcn(app, @VdivAKnob_2ValueChanged, true);
            app.ChannelBLabel_2 = uilabel(app.PositionPanel);
            app.ChannelBLabel_2.Text = 'Channel B';
            app.VdivBKnob_2 = uiknob(app.PositionPanel, 'continuous');
            app.VdivBKnob_2.Limits = [-1 1];
            app.VdivBKnob_2.MajorTicks = 1;
            app.VdivBKnob_2.MajorTickLabels = {''};
            app.VdivBKnob_2.ValueChangedFcn = createCallbackFcn(app, @VdivBKnob_2ValueChanged, true);
            app.ChannelCLabel_2 = uilabel(app.PositionPanel);
            app.ChannelCLabel_2.Text = 'Channel C';
            app.VdivCKnob_2 = uiknob(app.PositionPanel, 'continuous');
            app.VdivCKnob_2.Limits = [-1 1];
            app.VdivCKnob_2.MajorTicks = 1;
            app.VdivCKnob_2.MajorTickLabels = {''};
            app.VdivCKnob_2.ValueChangedFcn = createCallbackFcn(app, @VdivCKnob_2ValueChanged, true);
            app.ChannelDLabel_2 = uilabel(app.PositionPanel);
            app.ChannelDLabel_2.Text = 'Channel D';
            app.VdivDKnob_2 = uiknob(app.PositionPanel, 'continuous');
            app.VdivDKnob_2.Limits = [-1 1];
            app.VdivDKnob_2.MajorTicks = 1;
            app.VdivDKnob_2.MajorTickLabels = {''};
            app.VdivDKnob_2.ValueChangedFcn = createCallbackFcn(app, @VdivDKnob_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end